classdef Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InterfaceTab                    matlab.ui.container.Tab
        BRAKEButton                     matlab.ui.control.Button
        LeftdistancetoobjectGaugeLabel  matlab.ui.control.Label
        LeftDistGauge                   matlab.ui.control.LinearGauge
        RightdistancetoobjectGaugeLabel  matlab.ui.control.Label
        RightDistGauge                  matlab.ui.control.LinearGauge
        DirectionGaugeLabel             matlab.ui.control.Label
        DirectionGauge                  matlab.ui.control.SemicircularGauge
        MotorspeedGaugeLabel            matlab.ui.control.Label
        MotorspeedGauge                 matlab.ui.control.SemicircularGauge
        DashboardLabel                  matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        StopdistancebeforeobjectcmEditFieldLabel  matlab.ui.control.Label
        stop_distance                   matlab.ui.control.NumericEditField
        Forward                         matlab.ui.control.Button
        Backward                        matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Left                            matlab.ui.control.Button
        ManualcontrolsLabel             matlab.ui.control.Label
        OpenserialportButton            matlab.ui.control.Button
        COMportLabel                    matlab.ui.control.Label
        comportValue                    matlab.ui.control.NumericEditField
        CloseserialportButton           matlab.ui.control.Button
        BatteryvoltageGaugeLabel        matlab.ui.control.Label
        BatteryvoltageGauge             matlab.ui.control.Gauge
        DataloggerTab                   matlab.ui.container.Tab
        DataloggerLabel                 matlab.ui.control.Label
        LeftDistGraph                   matlab.ui.control.UIAxes
        RightDistGraph                  matlab.ui.control.UIAxes
        DirectionGraph                  matlab.ui.control.UIAxes
        MotorspeedGraph                 matlab.ui.control.UIAxes
        SavedataloggingButton           matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function update(app)
            global index_log d_l d_r motorspeed direction d_l_log d_r_log motorspeed_log direction_log t voltage_log voltage
            toc
            %update the gauges
            app.getStatus()
            app.RightDistGauge.Value = d_r;
            app.LeftDistGauge.Value = d_l;
            app.DirectionGauge.Value = direction;
            app.MotorspeedGauge.Value = motorspeed;
            app.BatteryvoltageGauge.Value = voltage;
            
            %update the datalogging
            index_log = index_log + 1;
            d_l_log(index_log) = d_l;
            d_r_log(index_log) = d_r;
%             direction_log(index_log) = direction;
%             motorspeed_log(index_log) = motorspeed;
%             voltage_log(index_log) = voltage;
            t(index_log) = toc;
        end
        
        function getStatus(app)
            global d_l d_r motorspeed direction voltage
            Str = EPOCommunications('transmit','Sd');
            Index_l = strfind(Str, 'USL');
            d_l = sscanf(Str(Index_l(1) + 3:end), '%g', 1);
            Index_r = strfind(Str, 'USR');
            d_r = sscanf(Str(Index_r(1) + 3:end), '%g', 1);
%             Index_d = strfind(Str, 'Dir.');
%             direction = sscanf(Str(Index_d(1) + 4:end), '%g', 1);
%             Index_m = strfind(Str, 'Mot.');
%             motorspeed = sscanf(Str(Index_m(1) + 4:end), '%g', 1);
%             Index_v = strfind(Str, 'V_batt');
%             voltage = sscanf(Str(Index_v(1) + 6:end), '%g', 1);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global index_log motorspeed direction motorspeed_log direction_log d_l d_r d_l_log d_r_log t voltage_log
            voltage_log = 0;
            motorspeed_log = 0;
            motorspeed = 0;
            direction = 0;
            direction_log = 0;
            d_l = 0;
            d_r = 0;
            d_l_log = 0;
            d_r_log = 0;
            t = 0;
            index_log = 0;
            
            DashboardTimer = timer;
            DashboardTimer.ExecutionMode = 'fixedSpacing';
            DashboardTimer.Period = 0.05;
            DashboardTimer.TimerFcn = @(~,~)app.update;
            pause(1)
            start(DashboardTimer)
            tic
            
        end

        % Button pushed function: BRAKEButton
        function BRAKEButtonPushed(app, event)
            EPOCommunications('transmit','D150');
            EPOCommunications('transmit','M135');
            pause(2);
            EPOCommunications('transmit','M150');
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            stopAtDistance(app.stop_distance.Value);
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            EPOCommunications('transmit','D150');
            EPOCommunications('transmit','M165');
            %pause(1)
            %EPOCommunications('transmit','M150');
            %             checkSensors();
            %             update(app);
        end

        % Button pushed function: Backward
        function BackwardButtonPushed(app, event)
            EPOCommunications('transmit','D150');
            EPOCommunications('transmit','M140');
            pause(1)
            EPOCommunications('transmit','M150');
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            EPOCommunications('transmit','D200');
            EPOCommunications('transmit','M160');
            pause(1)
            EPOCommunications('transmit','M150');
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            EPOCommunications('transmit','D100');
            EPOCommunications('transmit','M160');
            pause(1)
            EPOCommunications('transmit','M150');
        end

        % Button pushed function: SavedataloggingButton
        function SavedataloggingButtonPushed(app, event)
            saveDatalogging();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global d_l_log d_r_log t direction_log motorspeed_log
            plot(app.LeftDistGraph,t,d_l_log);
            plot(app.RightDistGraph,t,d_r_log);
            plot(app.DirectionGraph,t,direction_log);
            plot(app.MotorspeedGraph,t,motorspeed_log);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global d_l_log d_r_log t direction_log motorspeed_log
%             EPOCommunications('close');
            saveDatalogging();
            delete(app);
        end

        % Button pushed function: OpenserialportButton
        function OpenserialportButtonPushed(app, event)
            comport = sprintf('\\\\.\\COM%d',app.comportValue.Value)
            EPOCommunications('open',comport)
            
        end

        % Button pushed function: CloseserialportButton
        function CloseserialportButtonPushed(app, event)
            EPOCommunications('close');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterfaceTab = uitab(app.TabGroup);
            app.InterfaceTab.Title = 'Interface';
            app.InterfaceTab.Units = 'pixels';
            app.BRAKEButton = uibutton(app.InterfaceTab, 'push');
            app.BRAKEButton.ButtonPushedFcn = createCallbackFcn(app, @BRAKEButtonPushed, true);
            app.BRAKEButton.Text = 'BRAKE!';
            app.LeftdistancetoobjectGaugeLabel = uilabel(app.InterfaceTab);
            app.LeftdistancetoobjectGaugeLabel.Text = 'Left distance to object';
            app.LeftDistGauge = uigauge(app.InterfaceTab, 'linear');
            app.LeftDistGauge.Limits = [0 400];
            app.LeftDistGauge.MajorTicks = [0 50 100 150 200 250 300 350 400];
            app.RightdistancetoobjectGaugeLabel = uilabel(app.InterfaceTab);
            app.RightdistancetoobjectGaugeLabel.Text = 'Right distance to object';
            app.RightDistGauge = uigauge(app.InterfaceTab, 'linear');
            app.RightDistGauge.Limits = [0 400];
            app.RightDistGauge.MajorTicks = [0 50 100 150 200 250 300 350 400];
            app.DirectionGaugeLabel = uilabel(app.InterfaceTab);
            app.DirectionGaugeLabel.Text = 'Direction';
            app.DirectionGauge = uigauge(app.InterfaceTab, 'semicircular');
            app.DirectionGauge.Limits = [100 200];
            app.DirectionGauge.MajorTicks = [100 125 150 175 200];
            app.DirectionGauge.Value = 150;
            app.MotorspeedGaugeLabel = uilabel(app.InterfaceTab);
            app.MotorspeedGaugeLabel.Text = 'Motor speed';
            app.MotorspeedGauge = uigauge(app.InterfaceTab, 'semicircular');
            app.MotorspeedGauge.Limits = [135 165];
            app.MotorspeedGauge.MajorTicks = [135 140 145 150 155 160 165];
            app.MotorspeedGauge.Value = 150;
            app.DashboardLabel = uilabel(app.InterfaceTab);
            app.DashboardLabel.Text = 'Dashboard';
            app.GoButton = uibutton(app.InterfaceTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.StopdistancebeforeobjectcmEditFieldLabel = uilabel(app.InterfaceTab);
            app.StopdistancebeforeobjectcmEditFieldLabel.Text = 'Stop distance before object (cm)';
            app.stop_distance = uieditfield(app.InterfaceTab, 'numeric');
            app.stop_distance.Limits = [100 500];
            app.stop_distance.Value = 100;
            app.Forward = uibutton(app.InterfaceTab, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Text = '↑';
            app.Backward = uibutton(app.InterfaceTab, 'push');
            app.Backward.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.Backward.Text = '↓';
            app.Right = uibutton(app.InterfaceTab, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '→';
            app.Left = uibutton(app.InterfaceTab, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '←';
            app.ManualcontrolsLabel = uilabel(app.InterfaceTab);
            app.ManualcontrolsLabel.Text = 'Manual controls:';
            app.OpenserialportButton = uibutton(app.InterfaceTab, 'push');
            app.OpenserialportButton.ButtonPushedFcn = createCallbackFcn(app, @OpenserialportButtonPushed, true);
            app.OpenserialportButton.Text = 'Open serial port';
            app.COMportLabel = uilabel(app.InterfaceTab);
            app.COMportLabel.Text = 'COM port =';
            app.comportValue = uieditfield(app.InterfaceTab, 'numeric');
            app.CloseserialportButton = uibutton(app.InterfaceTab, 'push');
            app.CloseserialportButton.ButtonPushedFcn = createCallbackFcn(app, @CloseserialportButtonPushed, true);
            app.CloseserialportButton.Text = 'Close serial port';
            app.BatteryvoltageGaugeLabel = uilabel(app.InterfaceTab);
            app.BatteryvoltageGaugeLabel.Text = 'Battery voltage';
            app.BatteryvoltageGauge = uigauge(app.InterfaceTab, 'circular');
            app.BatteryvoltageGauge.Limits = [17.5 18.5];
            app.BatteryvoltageGauge.MajorTicks = [17.5 17.75 18 18.25 18.5];
            app.BatteryvoltageGauge.Value = 18.5;
            app.DataloggerTab = uitab(app.TabGroup);
            app.DataloggerTab.Title = 'Data logger';
            app.DataloggerTab.Units = 'pixels';
            app.DataloggerLabel = uilabel(app.DataloggerTab);
            app.DataloggerLabel.Text = 'Data logger';
            app.LeftDistGraph = uiaxes(app.DataloggerTab);
            title(app.LeftDistGraph, 'Left distance to wall');
            xlabel(app.LeftDistGraph, 'Time (s)');
            ylabel(app.LeftDistGraph, 'Distance (cm)');
            app.RightDistGraph = uiaxes(app.DataloggerTab);
            title(app.RightDistGraph, 'Right distance to wall');
            xlabel(app.RightDistGraph, 'Time (s)');
            ylabel(app.RightDistGraph, 'Distance (cm)');
            app.DirectionGraph = uiaxes(app.DataloggerTab);
            title(app.DirectionGraph, 'Direction');
            xlabel(app.DirectionGraph, 'Time (s)');
            ylabel(app.DirectionGraph, 'Direction');
            app.MotorspeedGraph = uiaxes(app.DataloggerTab);
            title(app.MotorspeedGraph, 'Motor speed');
            xlabel(app.MotorspeedGraph, 'Time (s)');
            ylabel(app.MotorspeedGraph, 'Motor speed');
            app.SavedataloggingButton = uibutton(app.DataloggerTab, 'push');
            app.SavedataloggingButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataloggingButtonPushed, true);
            app.SavedataloggingButton.Text = 'Save datalogging';
            app.PlotButton = uibutton(app.DataloggerTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dashboard()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end